classdef Group13 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        ParameterEditField       matlab.ui.control.EditField
        ParameterEditFieldLabel  matlab.ui.control.Label
        diagonal_notDiagonal     matlab.ui.control.ListBox
        ThresholdMethod          matlab.ui.control.ListBox
        OperationsListBox        matlab.ui.control.ListBox
        OperationsListBoxLabel   matlab.ui.control.Label
        SelectImageButton        matlab.ui.control.Button
        UIAxes_3                 matlab.ui.control.UIAxes
        UIAxes_2                 matlab.ui.control.UIAxes
        UIAxes                   matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        selectedImage
        HSI
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: SelectImageButton
        function SelectImageButtonPushed(app, event)
            [file, path] = uigetfile({'*.jpg;*.png;*.bmp;*.pgm;*.gif', 'Image Files (*.jpg, *.png, *.bmp, *.pgm, *.gif)'; '*.*', 'All Files (*.*)'}, 'Bir görüntü seçin');
            
            if isequal(file, 0)
                return;
            else
                fullFilePath = fullfile(path, file);
                app.selectedImage = imread(fullFilePath);
            end
            
            %Call the normalizing function
            normalizedImg=mynormalize(app.selectedImage);

            app.selectedImage=im2double(app.selectedImage);
            app.HSI= rgbtohsi(normalizedImg);

            Hue=app.HSI(:,:,1);
            Saturation=app.HSI(:,:,2);
            Intensity=app.HSI(:,:,3);

            imshow(Hue, 'Parent', app.UIAxes);
            imshow(Saturation, 'Parent', app.UIAxes_2);
            imshow(Intensity, 'Parent', app.UIAxes_3);

        end

        % Clicked callback: OperationsListBox
        function OperationsListBoxClicked(app, event)
            selectedOperation = app.OperationsListBox.Value;
            img=app.HSI(:,:,3);

            diagonalOrNot=app.diagonal_notDiagonal.Value;
            threshold=app.ThresholdMethod.Value;
            parameter=app.ParameterEditField.Value;
            switch selectedOperation
                case 'Prewitt'
                    processedImage=myedge(selectedOperation,img,diagonalOrNot,threshold,parameter);
                    imshow(processedImage,'Parent',app.UIAxes_3);
            end
                    
        end

        % Clicked callback: diagonal_notDiagonal
        function diagonal_notDiagonalClicked(app, event)
            app.ThresholdMethod.Visible="on";
        end

        % Clicked callback: ThresholdMethod
        function ThresholdMethodClicked(app, event)
            if strcmp(app.ThresholdMethod.Value,'Basic Global Thresholding')
                app.ParameterEditField.Visible="on";

            elseif strcmp(app.ThresholdMethod.Value,"Otsu's method")
                app.ParameterEditField.Visible="off";
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Hue')
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.UIAxes_2 = uiaxes(app.UIFigure);
            title(app.UIAxes_2, 'Saturation')
            app.UIAxes_2.XTick = [];
            app.UIAxes_2.YTick = [];
            app.UIAxes_3 = uiaxes(app.UIFigure);
            title(app.UIAxes_3, 'Intensity')
            app.UIAxes_3.XTick = [];
            app.UIAxes_3.YTick = [];
            app.SelectImageButton = uibutton(app.UIFigure, 'push');
            app.SelectImageButton.ButtonPushedFcn = createCallbackFcn(app, @SelectImageButtonPushed, true);
            app.SelectImageButton.Text = 'Select Image';
            app.OperationsListBoxLabel = uilabel(app.UIFigure);
            app.OperationsListBoxLabel.Text = 'Operations';
            app.OperationsListBox = uilistbox(app.UIFigure);
            app.OperationsListBox.Items = {'Prewitt', 'Sobel', 'Kirsch', 'Scharr', 'Laplacian of Gaussian', 'Canny Edge Detector', ''};
            app.OperationsListBox.ClickedFcn = createCallbackFcn(app, @OperationsListBoxClicked, true);
            app.OperationsListBox.Value = 'Prewitt';
            app.ThresholdMethod = uilistbox(app.UIFigure);
            app.ThresholdMethod.Items = {'Basic Global Thresholding', 'Otsu''s method'};
            app.ThresholdMethod.Visible = 'off';
            app.ThresholdMethod.ClickedFcn = createCallbackFcn(app, @ThresholdMethodClicked, true);
            app.ThresholdMethod.Value = 'Basic Global Thresholding';
            app.diagonal_notDiagonal = uilistbox(app.UIFigure);
            app.diagonal_notDiagonal.Items = {'With diagonal', 'Without diagonal'};
            app.diagonal_notDiagonal.Visible = 'off';
            app.diagonal_notDiagonal.ClickedFcn = createCallbackFcn(app, @diagonal_notDiagonalClicked, true);
            app.diagonal_notDiagonal.Value = 'With diagonal';
            app.ParameterEditFieldLabel = uilabel(app.UIFigure);
            app.ParameterEditFieldLabel.Visible = 'off';
            app.ParameterEditFieldLabel.Text = 'Parameter';
            app.ParameterEditField = uieditfield(app.UIFigure, 'text');
            app.ParameterEditField.Visible = 'off';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Group13

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end